classdef App_Example < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        Label1                matlab.ui.control.Label
        open_file             matlab.ui.control.Button
        apply                 matlab.ui.control.Button
        ThresholdingPanel     matlab.ui.container.Panel
        HighEditFieldLabel    matlab.ui.control.Label
        HighEditField         matlab.ui.control.EditField
        MorphologyPanel       matlab.ui.container.Panel
        NCloseEditFieldLabel  matlab.ui.control.Label
        NCloseEditField       matlab.ui.control.EditField
        NOpenEditFieldLabel   matlab.ui.control.Label
        NOpenEditField        matlab.ui.control.EditField
        LowEditFieldLabel     matlab.ui.control.Label
        LowEditField          matlab.ui.control.EditField
        imag_orig             matlab.ui.control.UIAxes
        RightPanel            matlab.ui.container.Panel
        Label2                matlab.ui.control.Label
        copy_orig             matlab.ui.control.Button
        saveas                matlab.ui.control.Button
        new_window            matlab.ui.control.Button
        imag_processed        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        imag_orig_mat % pixel values of the original image
        imag_proc_mat % pixel values of the processed image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            axis(app.imag_orig, 'image');
            axis(app.imag_processed, 'image');            
        end

        % Button pushed function: open_file
        function open_fileButtonPushed(app, event)
            
            [filename, pathname]= uigetfile({'*.jpg;*.tif;*.png;*.gif','All Image Files'; '*.*','All Files' },'Choose an image file ...');
            
            if isequal(filename,0)
               warndlg('File not selected');
               return
            end
            
            image=imread(strcat(pathname,filename));
            
            if not(size(image,3)==3)
               errordlg('The image must be colored');
               return
            end
            
            imshow(image,'Parent',app.imag_orig,'InitialMagnification','fit');
            %imagesc(app.imag_orig,image);
            
            app.imag_orig_mat=image;            
        end

        % Button pushed function: saveas
        function saveasButtonPushed(app, event)

            [filename, pathname]= uiputfile({'*.jpg;*.tif;*.png;*.gif','All Image Files'; '*.*','All Files' },'Save image file as ...');
            
            if isequal(filename,0)
               warndlg('File not selected');
               return
            end
            
            image=app.imag_proc_mat;
            
            imwrite(image,strcat(pathname,filename));            
        end

        % Button pushed function: new_window
        function new_windowPushed(app, event)

            image=app.imag_proc_mat;
            
            h=figure;
            imshow(image,'Parent',axes(h));            
        end

        % Button pushed function: copy_orig
        function copy_origButtonPushed(app, event)

            image=app.imag_proc_mat;
            
            imshow(image,'Parent',app.imag_orig);
            
            app.imag_orig_mat=image;            
        end

        % Button pushed function: apply
        function applyButtonPushed(app, event)

            image=app.imag_orig_mat;
            image_d=double(image)/255;
            
            image_proc=(image_d(:,:,1)+image_d(:,:,2)+image_d(:,:,3))/3; % intensity component
            
            imshow(image_proc,'Parent',app.imag_processed);
            
            app.imag_proc_mat=image_proc;            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {490, 490};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {428, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label1 = uilabel(app.LeftPanel);
            app.Label1.Text = 'ORIGINAL IMAGE';
            app.open_file = uibutton(app.LeftPanel, 'push');
            app.open_file.ButtonPushedFcn = createCallbackFcn(app, @open_fileButtonPushed, true);
            app.open_file.Text = 'Abrir Imagen';
            app.apply = uibutton(app.LeftPanel, 'push');
            app.apply.ButtonPushedFcn = createCallbackFcn(app, @applyButtonPushed, true);
            app.apply.Text = 'APPLY';
            app.imag_orig = uiaxes(app.LeftPanel);
            app.imag_orig.XTick = [];
            app.imag_orig.XTickLabel = {'[ ]'};
            app.imag_orig.YTick = [];
            app.imag_orig.Visible = 'off';
            app.ThresholdingPanel = uipanel(app.LeftPanel);
            app.ThresholdingPanel.Title = 'Thresholding';
            app.LowEditFieldLabel = uilabel(app.ThresholdingPanel);
            app.LowEditFieldLabel.Text = 'Low';
            app.LowEditField = uieditfield(app.ThresholdingPanel, 'text');
            app.HighEditFieldLabel = uilabel(app.ThresholdingPanel);
            app.HighEditFieldLabel.Text = 'High';
            app.HighEditField = uieditfield(app.ThresholdingPanel, 'text');
            app.MorphologyPanel = uipanel(app.LeftPanel);
            app.MorphologyPanel.Title = 'Morphology';
            app.NCloseEditFieldLabel = uilabel(app.MorphologyPanel);
            app.NCloseEditFieldLabel.Text = 'N Close';
            app.NCloseEditField = uieditfield(app.MorphologyPanel, 'text');
            app.NOpenEditFieldLabel = uilabel(app.MorphologyPanel);
            app.NOpenEditFieldLabel.Text = 'N Open';
            app.NOpenEditField = uieditfield(app.MorphologyPanel, 'text');
            app.RightPanel = uipanel(app.GridLayout);
            app.Label2 = uilabel(app.RightPanel);
            app.Label2.Text = 'PROCESSED IMAGE';
            app.copy_orig = uibutton(app.RightPanel, 'push');
            app.copy_orig.ButtonPushedFcn = createCallbackFcn(app, @copy_origButtonPushed, true);
            app.copy_orig.Text = 'Copy to Original';
            app.saveas = uibutton(app.RightPanel, 'push');
            app.saveas.ButtonPushedFcn = createCallbackFcn(app, @saveasButtonPushed, true);
            app.saveas.Text = 'Save As ...';
            app.new_window = uibutton(app.RightPanel, 'push');
            app.new_window.ButtonPushedFcn = createCallbackFcn(app, @new_windowPushed, true);
            app.new_window.Text = 'New Window';
            app.imag_processed = uiaxes(app.RightPanel);
            app.imag_processed.XTick = [];
            app.imag_processed.XTickLabel = {'[ ]'};
            app.imag_processed.YTick = [];
            app.imag_processed.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Example

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end