classdef App_Proyecto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        Label1                     matlab.ui.control.Label
        open_file                  matlab.ui.control.Button
        apply                      matlab.ui.control.Button
        imag_orig                  matlab.ui.control.UIAxes
        UmbralizacionPanel         matlab.ui.container.Panel
        UmbralEditFieldLabel       matlab.ui.control.Label
        UmbralEditField            matlab.ui.control.NumericEditField
        MorphologyPanel            matlab.ui.container.Panel
        NcloseEditFieldLabel       matlab.ui.control.Label
        NcloseEditField            matlab.ui.control.NumericEditField
        NopenEditFieldLabel        matlab.ui.control.Label
        NopenEditField             matlab.ui.control.NumericEditField
        TamaominimoEditFieldLabel  matlab.ui.control.Label
        TamaominimoEditField       matlab.ui.control.NumericEditField
        RightPanel                 matlab.ui.container.Panel
        Label2                     matlab.ui.control.Label
        copy_orig                  matlab.ui.control.Button
        saveas                     matlab.ui.control.Button
        new_window                 matlab.ui.control.Button
        imag_processed             matlab.ui.control.UIAxes
        imag_processed_2           matlab.ui.control.UIAxes
        SegmentacionLabel          matlab.ui.control.Label
        ClasificacionLabel         matlab.ui.control.Label
        NumerodecochesLabel        matlab.ui.control.Label
        NumeroCoches               matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        imag_orig_mat % pixel values of the original image
        imag_proc_mat % pixel values of the processed image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            axis(app.imag_orig, 'image');
            axis(app.imag_processed, 'image');            
        end

        % Button pushed function: open_file
        function open_fileButtonPushed(app, event)
            
            [filename, pathname]= uigetfile({'*.jpg;*.tif;*.png;*.gif','All Image Files'; '*.*','All Files' },'Choose an image file ...');
            
            if isequal(filename,0)
               warndlg('File not selected');
               return
            end
            
            image=imread(strcat(pathname,filename));
            
            if not(size(image,3)==3)
               errordlg('The image must be colored');
               return
            end
            
            imshow(image,'Parent',app.imag_orig,'InitialMagnification','fit');
            %imagesc(app.imag_orig,image);
            
            app.imag_orig_mat=image;            
        end

        % Button pushed function: saveas
        function saveasButtonPushed(app, event)

            [filename, pathname]= uiputfile({'*.jpg;*.tif;*.png;*.gif','All Image Files'; '*.*','All Files' },'Save image file as ...');
            
            if isequal(filename,0)
               warndlg('File not selected');
               return
            end
            
            image=app.imag_proc_mat;
            
            imwrite(image,strcat(pathname,filename));            
        end

        % Button pushed function: new_window
        function new_windowPushed(app, event)

            image=app.imag_proc_mat;
            
            h=figure;
            imshow(image,'Parent',axes(h));            
        end

        % Button pushed function: copy_orig
        function copy_origButtonPushed(app, event)

            image=app.imag_proc_mat;
            
            imshow(image,'Parent',app.imag_orig);
            
            app.imag_orig_mat=image;            
        end

        % Button pushed function: apply
        function applyButtonPushed(app, event)
            
            % obtener la imagen 
            image=app.imag_orig_mat;
            image_d=double(image)/255;
            
           
            %------------------------------------------------------
            % segmentacion 
            %------------------------------------------------------
            % 1. convertir a escala de grises
            imgGris = rgb2gray(image_d);
            % 2. obtener umbral y aplicar umbralizacion
            
            umbral = app.UmbralEditField.Value;
         
            imgBinaria = imbinarize(imgGris,'adaptive','ForegroundPolarity','dark','Sensitivity',umbral);
            imgBinaria = imcomplement(imgBinaria);
         
            %------------------------------------------------------
            % morfologia 
            %------------------------------------------------------
            nClose = app.NcloseEditField.Value;
            nOpen = app.NopenEditField.Value;
            
            se = strel('square',4); % mascara
            
            % close
            for i = 1:nClose 
                imgBinaria =  imdilate(imgBinaria,se);
            end
            
            % open
            for i = 1:nOpen 
                imgBinaria =  imerode(imgBinaria,se);
            end 


            %imgBinaria = imfill(imgBinaria,'holes');
            
            %------------------------------------------------------
            % descripcion
            %------------------------------------------------------
            tamanyoDetector = app.TamaominimoEditField.Value;
            blobAnalysis = vision.BlobAnalysis('BoundingBoxOutputPort', true, 'AreaOutputPort', false, 'CentroidOutputPort', false, 'MinimumBlobArea', tamanyoDetector);
            
            bbox = step(blobAnalysis, imgBinaria);
            
            % numero de coches
            numCars = size(bbox, 1);
            app.NumeroCoches.Text = num2str(numCars);
            
            areaCoches = (1:numCars);
            % areas cajas
            for i = 1:numCars
                cuadroCoche = bbox(i,:);
                areaCoches(i) = cuadroCoche(3) * cuadroCoche(4);
            end


            % ordenar de mayor a menor area
            band = false;
            while ~band
                band = true;
                for i=1:numCars-1
                    if(areaCoches(i)<areaCoches(i+1))
                        % cambiar areas
                        auxArea = areaCoches(i+1);
                        areaCoches(i+1) = areaCoches(i);
                        areaCoches(i) = auxArea;
                        
                        % cambiar rectangulos
                        auxBox = bbox(i+1,:);
                        bbox(i+1,:) = bbox(i,:);
                        bbox(i,:) = auxBox;
                        
                        band = false;
                    end
                end
            end
            
            
            %------------------------------------------------------
            %clasificacion
            %------------------------------------------------------
            image_proc = insertShape(image, 'Rectangle', bbox, 'Color', 'green');
            
             for i = 1:numCars
               image_proc= insertText(image_proc, [bbox(i,1),bbox(i,2)], i, 'BoxOpacity', 1,'FontSize', 14);
            end
            
            %lab_he = rgb2lab(image);
            %ab = lab_he(:,:,2:3); ab = im2single(ab); nColors = 3;
            %imshow(pixel_labels,[]), title('Image Labeled by Cluster Index');
            
            % mostrar la imagen procesada
            imshow(image_proc,'Parent',app.imag_processed);
            imshow(imgBinaria,'Parent',app.imag_processed_2);
            
            app.imag_proc_mat=image_proc;            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {490, 490};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {428, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label1 = uilabel(app.LeftPanel);
            app.Label1.Text = 'ORIGINAL IMAGE';
            app.open_file = uibutton(app.LeftPanel, 'push');
            app.open_file.ButtonPushedFcn = createCallbackFcn(app, @open_fileButtonPushed, true);
            app.open_file.Text = 'Abrir Imagen';
            app.apply = uibutton(app.LeftPanel, 'push');
            app.apply.ButtonPushedFcn = createCallbackFcn(app, @applyButtonPushed, true);
            app.apply.Text = 'APPLY';
            app.imag_orig = uiaxes(app.LeftPanel);
            app.imag_orig.XTick = [];
            app.imag_orig.XTickLabel = {'[ ]'};
            app.imag_orig.YTick = [];
            app.imag_orig.Visible = 'off';
            app.UmbralizacionPanel = uipanel(app.LeftPanel);
            app.UmbralizacionPanel.Title = 'Umbralizacion';
            app.UmbralEditFieldLabel = uilabel(app.UmbralizacionPanel);
            app.UmbralEditFieldLabel.Text = 'Umbral';
            app.UmbralEditField = uieditfield(app.UmbralizacionPanel, 'numeric');
            app.UmbralEditField.Limits = [0 1];
            app.UmbralEditField.Value = 0.2;
            app.MorphologyPanel = uipanel(app.LeftPanel);
            app.MorphologyPanel.Title = 'Morphology';
            app.NcloseEditFieldLabel = uilabel(app.MorphologyPanel);
            app.NcloseEditFieldLabel.Text = 'N close';
            app.NcloseEditField = uieditfield(app.MorphologyPanel, 'numeric');
            app.NcloseEditField.Limits = [0 Inf];
            app.NopenEditFieldLabel = uilabel(app.MorphologyPanel);
            app.NopenEditFieldLabel.Text = 'N open';
            app.NopenEditField = uieditfield(app.MorphologyPanel, 'numeric');
            app.NopenEditField.Limits = [0 Inf];
            app.TamaominimoEditFieldLabel = uilabel(app.LeftPanel);
            app.TamaominimoEditFieldLabel.Text = 'Tamaño minimo';
            app.TamaominimoEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TamaominimoEditField.Value = 300;
            app.RightPanel = uipanel(app.GridLayout);
            app.Label2 = uilabel(app.RightPanel);
            app.Label2.Text = 'PROCESSED IMAGE';
            app.copy_orig = uibutton(app.RightPanel, 'push');
            app.copy_orig.ButtonPushedFcn = createCallbackFcn(app, @copy_origButtonPushed, true);
            app.copy_orig.Text = 'Copy to Original';
            app.saveas = uibutton(app.RightPanel, 'push');
            app.saveas.ButtonPushedFcn = createCallbackFcn(app, @saveasButtonPushed, true);
            app.saveas.Text = 'Save As ...';
            app.new_window = uibutton(app.RightPanel, 'push');
            app.new_window.ButtonPushedFcn = createCallbackFcn(app, @new_windowPushed, true);
            app.new_window.Text = 'New Window';
            app.imag_processed = uiaxes(app.RightPanel);
            app.imag_processed.XTick = [];
            app.imag_processed.XTickLabel = {'[ ]'};
            app.imag_processed.YTick = [];
            app.imag_processed.Visible = 'off';
            app.imag_processed_2 = uiaxes(app.RightPanel);
            app.imag_processed_2.XTick = [];
            app.imag_processed_2.XTickLabel = {'[ ]'};
            app.imag_processed_2.YTick = [];
            app.imag_processed_2.Visible = 'off';
            app.SegmentacionLabel = uilabel(app.RightPanel);
            app.SegmentacionLabel.Text = 'Segmentacion';
            app.ClasificacionLabel = uilabel(app.RightPanel);
            app.ClasificacionLabel.Text = 'Clasificacion';
            app.NumerodecochesLabel = uilabel(app.RightPanel);
            app.NumerodecochesLabel.Text = 'Numero de coches:';
            app.NumeroCoches = uilabel(app.RightPanel);
            app.NumeroCoches.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Proyecto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end